classdef CS2000_menue_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        file_menue              matlab.ui.container.Menu
        Menu                    matlab.ui.container.Menu
        save_menue              matlab.ui.container.Menu
        savePlot                matlab.ui.container.Menu
        storedMeasurements      matlab.ui.container.Menu
        exit_menue              matlab.ui.container.Menu
        Untitled_1              matlab.ui.container.Menu
        logarithmic_plot_menu   matlab.ui.container.Menu
        linear_plot_menu        matlab.ui.container.Menu
        uitoolbar1              matlab.ui.container.Toolbar
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        usercmfbox              matlab.ui.control.CheckBox
        cmfLabel                matlab.ui.control.Label
        cie17010Label           matlab.ui.control.Label
        cie1702Label            matlab.ui.control.Label
        cie196410Label          matlab.ui.control.Label
        cie19312Label           matlab.ui.control.Label
        biseshuju               matlab.ui.control.Table
        startMeasure            matlab.ui.control.Button
        normalLightBox          matlab.ui.control.CheckBox
        staticCommentsText      matlab.ui.control.Label
        commentsEdit            matlab.ui.control.TextArea
        staticLightSourceText   matlab.ui.control.Label
        lightSourceEdit         matlab.ui.control.EditField
        NDfilterMenu            matlab.ui.control.DropDown
        staticNDfilterText      matlab.ui.control.Label
        staticNumberOfMeasText  matlab.ui.control.Label
        numberOfMeas            matlab.ui.control.EditField
        uipanel1                matlab.ui.container.Panel
        colorDataNames          matlab.ui.control.Label
        colorDataText           matlab.ui.control.Label
        axes1                   matlab.ui.control.UIAxes
        Tab_2                   matlab.ui.container.Tab
        Button_2                matlab.ui.control.Button
        DropDown_jiaodu         matlab.ui.control.DropDown
        DropDown_2Label         matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        EditField_color         matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        EditField_jiaodu        matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        Button                  matlab.ui.control.Button
        sezuobiao               matlab.ui.control.UIAxes
        duibidubiaoge           matlab.ui.control.UIAxes
        duvtu                   matlab.ui.control.UIAxes
        Tab_3                   matlab.ui.container.Tab
        save_toushelv           matlab.ui.control.Button
        toushelv                matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
        Button_beice            matlab.ui.control.Button
        Button_yuashi           matlab.ui.control.Button
        toushelvtu              matlab.ui.control.UIAxes
        measureText             matlab.ui.control.Label
        measureText2            matlab.ui.control.Label
        openText                matlab.ui.control.Label
        instr_Status            matlab.ui.control.Label
        Status_Text             matlab.ui.control.Label
    end

    
    properties (Access = private)
        biaogew,biaoger,biaogeg,biaogeb,biaogebl,jiaodu,jiaoduxuanxiang,yansexuanxiang,biseshujuxianshi,yuanshiguangpu,usercmf,Y,beiceguangpu,yuanshiy,beicey,guangpu,biaoge,biseshujuzhi,toushe,toushelv2


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CS2000_menue_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to CS2000_menue (see VARARGIN)
            
            % Choose default command line output for CS2000_menue
            handles.output = hObject;
            
            % Update handles structure
            
            app.EditField_color.Value ='white';
            app.EditField_jiaodu.Value='90';
            app.jiaodu=90;
            
            app.biaogew =zeros(8,6) ;
            app.biaoger =zeros(8,6) ;
            app.biaoger =zeros(8,6) ;
            app.biaogeb =zeros(8,6) ;
            app.biaogebl=zeros(8,6) ;


            guidata(hObject, handles);
            
            CS2000_rubber(handles);
            message = evalin('base', 'message');
            set(handles.openText, 'String', message);


        end

        % Value changed function: NDfilterMenu
        function NDfilterMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to NDfilterMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            val = get(handles.NDfilterMenu, 'Value') -1;
            CS2000_setNDFilter(val);
            fil = CS2000_readNDFilter;
            switch fil
                case 0
                    filter = '无';
                case 1
                    filter = 'ND1';
                case 2
                    filter = 'ND2';
                otherwise
                    filter = '错误：无法读取ND滤镜';
            end
            set(handles.measureText, 'String', ['ND滤镜: ', filter]);
        end

        % Menu selected function: linear_plot_menu
        function changePlotScaleLinear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Untitled_1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set( gca, 'YScale', 'lin' );
        end

        % Menu selected function: logarithmic_plot_menu
        function changePlotScaleLog_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Untitled_1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set( gca, 'YScale', 'lin' );
        end

        % Menu selected function: exit_menue
        function exit_menue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit_menue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            user_response = questdlg('关闭CS2000菜单并终止连接？',...
                'Close Request Function',...
                'Yes','No','No');
            switch user_response
                case {'No'}
                    % take no action
                case 'Yes'
                    % Prepare to close GUI application window
                    guidata(hObject, handles);
                    pause(0.1);
                    CS2000_terminateConnection();
                    clear ;
                    delete(app);
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            user_response = questdlg('关闭CS2000菜单并终止连接？',...
                '关闭窗口ND',...
                '是','否','否');
            switch user_response
                case {'否'}
                    % take no action
                case '是'
                    % Prepare to close GUI application window
                    guidata(hObject, handles);
                    pause(0.1);
                    delete(app);
                    [~] = CS2000_terminateConnection();
                    pause(0.1);
                    clear ;
            end
        end

        % Value changed function: normalLightBox
        function normalLightBox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to normalLightBox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if (get(handles.normalLightBox,'Value') == get(hObject,'Max'))
                message = CS2000_setBacklight(1,0);
            else
                message = CS2000_setBacklight(1,1);
            end
            set(handles.measureText, 'String', message);
        end

        % Menu selected function: savePlot
        function savePlot_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to savePlot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            [file,path] = uiputfile('*.xlsx');
            xlsname = fullfile(path,file);
            xlswrite(xlsname,app.guangpu,1);
            xlswrite(xlsname,app.biseshujuzhi,1,'C1');
            bisebiaotou1=["","X","Y","Z","Y","x","y","u","v","CCT","JNCD"];
            bisebiaotou2=["cie1931";"cie1964";"cie170-2";"cie170-10"];
            
            xlswrite(xlsname,bisebiaotou1,1,'F1');
            xlswrite(xlsname,bisebiaotou2,1,'F2');
            
            xlswrite(xlsname,app.biseshujuxianshi,1,'G2');


        end

        % Menu selected function: save_menue
        function save_menue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to save_menue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            CS2000_chosePath;
            uiwait(CS2000_chosePath);
            file_name = evalin('base', 'file_name');
            numMeas = evalin('base', 'numMeas');
            measurements = evalin('base', 'measurements');
            % save in one .mat file:
            for i = 1 : numMeas
                measurements{i}.comments = get(handles.commentsEdit, 'String');
                measurements{i}.lightSource = get(handles.lightSourceEdit, 'String');
            end
            save(file_name, 'measurements');
        end

        % Button pushed function: startMeasure
        function startMeasure_Callback(app, event)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % hObject    handle to startMeasure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global    x y u v ;
            
            CS2000_rubber(handles);
            pause(0.1);
            numMeas = str2double(get(handles.numberOfMeas, 'String'));
            assignin('base', 'numMeas', numMeas);
            measurements = cell(numMeas,1);
            
            for i = 1 : numMeas
                % measure
                set(handles.measureText, 'String', ['测量... ', num2str(i), ...
                    '/', num2str(numMeas)]);
                pause(0.1);
                [message1, message2, measuredData, colorimetricNames] = CS2000_measure();
                aperture = CS2000_readApertureStop;
                measuredData.aperture = aperture;
                assignin('base', 'measuredData', measuredData);
                assignin('base', 'colorimetricNames', colorimetricNames);
                set(handles.measureText, 'String', message1);
                set(handles.measureText2, 'String', message2);
                
                set(handles.colorDataText, 'String', {measuredData.colorimetricData.Lv,...
                    measuredData.colorimetricData.X, measuredData.colorimetricData.Z,...
                    aperture, mat2str(measuredData.timeStamp),numMeas});
                set(handles.colorDataNames, 'String', {colorimetricNames{2},...
                    colorimetricNames{3}, colorimetricNames{5}, '光圈', '时间','','测量次数 '});
                pause(0.1);
                % create objects
                measuredData.comments = get(handles.commentsEdit, 'String');
                measuredData.lightSource = get(handles.lightSourceEdit, 'String');
                measurements{i} = measuredData;
                assignin('base', 'measurements', measurements);
                spectray = (380:780);
                plot(app.axes1,spectray,measuredData.spectralData);
            end
            
            
            % calculate means of measurement data
            if numMeas > 1
                meansText = '平均';
                set(handles.measureText, 'String', 'Calculating means of measurements...');
                drawnow;
                meansOfMeasurements = CS2000_calcMeansOfMeasuredData(measurements);
                %[Lp, Lm, Ls] = calcLuminance(meansOfMeasurements.spectralData);
                title([meansText,' of Spectral Radiance\fontsize{18}']);
                
                
            else
                meansText = '单次';
                %[Lp, Lm, Ls] = calcLuminance(measurements{1,1}.spectralData);
                meansOfMeasurements = measurements{1,1};
            end
            
            
            assignin('base', 'meansOfMeasurements', meansOfMeasurements);
            
            set(handles.colorDataText, 'String', {'',meansOfMeasurements.colorimetricData.Lv,...
                meansOfMeasurements.colorimetricData.X,meansOfMeasurements.colorimetricData.Y,...
                meansOfMeasurements.colorimetricData.Z, meansOfMeasurements.colorimetricData.x,...
                meansOfMeasurements.colorimetricData.y,aperture, datestr(meansOfMeasurements.timeStamp),numMeas});
            set(handles.colorDataNames, 'String', {['[',meansText,']'],'亮度',...
                'X', 'Y','Z', 'x','y' '光圈', '时间', ...
                '', '测量次数 ', });
            set(handles.measureText, 'String', ['正在显示第 ', num2str(numMeas), ' 次测量']);
            spectray = (380:780);
            plot(app.axes1,spectray,meansOfMeasurements.spectralData);
            drawnow;
            app.biseshujuzhi=["Lv",meansOfMeasurements.colorimetricData.Lv;...
                "X",meansOfMeasurements.colorimetricData.X;...
                "Y",meansOfMeasurements.colorimetricData.Y;...
                "Z",meansOfMeasurements.colorimetricData.Z;...
                "x",meansOfMeasurements.colorimetricData.x;...
                "y",meansOfMeasurements.colorimetricData.y;...
                "u_",meansOfMeasurements.colorimetricData.u_;...
                "v_",meansOfMeasurements.colorimetricData.v_;...
                "T",meansOfMeasurements.colorimetricData.T;...
                "Duv",meansOfMeasurements.colorimetricData.delta_uv;...
                "X10",meansOfMeasurements.colorimetricData.X10;...
                "Y10",meansOfMeasurements.colorimetricData.Y10;...
                "Z10",meansOfMeasurements.colorimetricData.Z10;...
                "x10",meansOfMeasurements.colorimetricData.x10;...
                "y10",meansOfMeasurements.colorimetricData.y10;...
                "T10",meansOfMeasurements.colorimetricData.T10];
            app.guangpu = meansOfMeasurements.spectralData.';
            app.Y =meansOfMeasurements.colorimetricData.Y;
            x =meansOfMeasurements.colorimetricData.x;
            y =meansOfMeasurements.colorimetricData.y;
            u =meansOfMeasurements.colorimetricData.u_;
            v =meansOfMeasurements.colorimetricData.v_;
            
            %计算cie170
            if app.usercmfbox.Value==0
                gpjisuan=guangpujisuan(app.guangpu,0);
                
            else
                gpjisuan=guangpujisuan(app.guangpu,app.usercm);
                
            end
            
            
            app.biseshujuxianshi(1:5,1:6)=gpjisuan;
            
%             load cie170.mat %计算cie170
%             assignin('base', 'cie170', cie170);
%             
%             cie170_2_X=gpjisuan(3:1);
%             cie170_2_Y=gpjisuan(3:2);
%             cie170_2_Z=gpjisuan(3:3);
%             cie170_2_x=gpjisuan(3:5);
%             cie170_2_y=gpjisuan(3:6);
%             %2度标准观察者为683.358
%             %10度标准观察者为683.144
%             
%             
%             cie170_10_X=gpjisuan(4:1);
%             cie170_10_Y=gpjisuan(4:2);
%             cie170_10_Z=gpjisuan(4:3);
%             cie170_10_x=gpjisuan(4:5);
%             cie170_10_y=gpjisuan(4:6);


            %格式化表格
            
          
            app.biseshujuxianshi(1,7) =meansOfMeasurements.colorimetricData.u_;
            app.biseshujuxianshi(1,8) =meansOfMeasurements.colorimetricData.v_;
            app.biseshujuxianshi(1,9) =meansOfMeasurements.colorimetricData.T;
            app.biseshujuxianshi(1,10) = meansOfMeasurements.colorimetricData.delta_uv*250;
            
            
            app.biseshujuxianshi(2,7) =meansOfMeasurements.colorimetricData.u_10;
            app.biseshujuxianshi(2,8) =meansOfMeasurements.colorimetricData.v_10;
            app.biseshujuxianshi(2,9) =meansOfMeasurements.colorimetricData.T10;
            app.biseshujuxianshi(2,10) =meansOfMeasurements.colorimetricData.delta_uv10*250;


            app.biseshuju.Data(1:5,1:10)=app.biseshujuxianshi(1:5,1:10);


        end

        % Menu selected function: storedMeasurements
        function storedMeasurements_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to storedMeasurements (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            checkfilename = 1;
            while checkfilename == 1
                CS2000_storedMeasurementFig;
                uiwait(CS2000_storedMeasurementFig);
                dir_name = evalin('base', 'dir_name');
                delete_check = evalin('base', 'delete_check');
                
                if ~(isempty(dir_name)) %save data
                    file_name = evalin('base', 'file_name');
                    filename = [dir_name, '\', file_name, '.mat'];
                    if ~(exist(filename))
                        checkreading = 1;
                        checkfilename = 0;
                    else
                        disp('Sorry, file already exists');
                        % Construct a questdlg with two options
                        choice = questdlg('File already exists. Overwrite?', ...
                            'Save Menu', ...
                            'YES','NO, type in another filename', 'NO, type in another filename');
                        % Handle response
                        switch choice
                            case 'YES'
                                checkreading = 1;
                                checkfilename = 0;
                            case 'NO, type in another filename'
                                checkreading = 0;
                                checkfilename = 1;
                            otherwise
                                checkreading = 0;
                                checkfilename = 1;
                        end
                    end
                    
                    if checkreading == 1
                        %get measurement object from camera:
                        text = 'Reading...';
                        set(handles.measureText, 'String', text);
                        disp(text);
                        drawnow;
                        [text, CS2000_allMeasurements] = CS2000_readStoredMeasurement();
                        set(handles.measureText, 'String', text);
                        disp(text);
                        drawnow;
                        %save object
                        if ~(isempty(CS2000_allMeasurements))
                            save(filename, 'CS2000_allMeasurements');
                            pause(0.1);
                            text = '.mat has been saved!';
                            set(handles.measureText, 'String', text);
                            disp(text);
                            pause(0.1);
                        else
                            set(handles.measureText, 'String', text);
                            disp(text);
                            pause(0.1);
                        end
                    end
                elseif ~(isempty(delete_check)) %delete data
                    checkfilename = 0;
                    if delete_check == 1
                        text = CS2000_deleteStoredMeasurements();
                        set(handles.measureText, 'String', text);
                    end
                end
            end
        end

        % Value changed function: DropDown_jiaodu
        function DropDown_jiaoduValueChanged(app, event)
            
            %角度选项卡
            
            app.jiaoduxuanxiang = app.DropDown_jiaodu.Value;
            if  app.jiaoduxuanxiang =="auto"
                
                app.EditField_jiaodu.Value=num2str(app.jiaodu);
            else
                app.EditField_jiaodu.Value=app.jiaoduxuanxiang;
                app.jiaodu=str2num(app.jiaoduxuanxiang);
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            %颜色选项卡
            
            
            app.yansexuanxiang = app.DropDown.Value;
            
            %颜色
            if  app.jiaoduxuanxiang =="auto"
                app.jiaodu = 90;
            end
            
            
            app.EditField_jiaodu.Value=num2str(app.jiaodu);
            
            
            app.EditField_color.Value= app.yansexuanxiang ;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            %可视角度测量
            
            
            global x y u v;
            %生成随机cie1931点
            %              x = rand(1)*0.7;
            %             y= rand(1)*0.8;
            %             Y=rand(1)*200;
            %             u = rand(1)*0.7;
            %            v= rand(1)*0.8;
            
            %判断颜色
            app.Button.Enable = 'off' ;%读取期间不可用。
            
            
            startMeasure_Callback(app,event)
            
            %自动时候判断颜色判断颜色
            
            app.yansexuanxiang = app.DropDown.Value;
            
            if app.yansexuanxiang == "auto"
                if app.Y <1
                    yanse="black";
                elseif y > 0.4
                    yanse ="green";
                elseif y <0.18 && x<0.3
                    yanse ="blue";
                elseif x>0.5
                    yanse="red";
                else
                    yanse="white";
                end
            else
                app.EditField_color.Value=app.yansexuanxiang;
                yanse=app.yansexuanxiang;
            end
            
            
            %自动修改角度
            
            app.jiaoduxuanxiang  = app.DropDown_jiaodu.Value;  %读取角度选项框


            app.EditField_jiaodu.Value=num2str(app.jiaodu);
            app.EditField_color.Value= yanse ;


            %建立矩阵
            biaogex = (100-app.jiaodu)/10 ;
            
            if yanse=="white"
                app.biaogew(biaogex,1) = app.Y;
                app.biaogew(biaogex,2) = x;
                app.biaogew(biaogex,3) = y;
                app.biaogew(biaogex,4) = u;
                app.biaogew(biaogex,5) = v;
                app.biaogew(biaogex,6) = ((app.biaogew(biaogex,4)-app.biaogew(1,4))^2+(app.biaogew(biaogex,4)-app.biaogew(1,4))^2)^0.5*250 ;%白色Duv
                
            elseif yanse=="red"
                app.biaoger(biaogex,1) = app.Y;
                app.biaoger(biaogex,2) = x;
                app.biaoger(biaogex,3) = y;
                app.biaoger(biaogex,4) = u;
                app.biaoger(biaogex,5) = v;
                app.biaoger(biaogex,6) = ((app.biaoger(biaogex,4)-app.biaoger(1,4))^2+(app.biaoger(biaogex,4)-app.biaoger(1,4))^2)^0.5*250 ;%红色Duv
                
                
            elseif yanse=="green"
                app.biaoger(biaogex,1) = app.Y;
                app.biaoger(biaogex,2) = x;
                app.biaoger(biaogex,3) = y;
                app.biaoger(biaogex,4) = u;
                app.biaoger(biaogex,5) = v;
                app.biaoger(biaogex,6) = ((app.biaoger(biaogex,4)-app.biaoger(1,4))^2+(app.biaoger(biaogex,4)-app.biaoger(1,4))^2)^0.5*250 ;%红色Duv
                
            elseif yanse=="blue"
                app.biaogeb(biaogex,1) = app.Y;
                app.biaogeb(biaogex,2) = x;
                app.biaogeb(biaogex,3) = y;
                app.biaogeb(biaogex,4) = u;
                app.biaogeb(biaogex,5) = v;
                app.biaogeb(biaogex,6) = ((app.biaogeb(biaogex,4)-app.biaogeb(1,4))^2+(app.biaogeb(biaogex,4)-app.biaogeb(1,4))^2)^0.5*250 ;%红色Duv
                
                
            elseif yanse=="black"
                app.biaogebl(biaogex,1) = app.Y;
                app.biaogebl(biaogex,2) = x;
                app.biaogebl(biaogex,3) = y;
                app.biaogebl(biaogex,4) = u;
                app.biaogebl(biaogex,5) = v;
                app.biaogebl(biaogex,6) = app.biaogew(biaogex,1)/app.biaogebl(biaogex,1); %计算对比度阵列
                
            end
            %转化为表格
            
            biaow = array2table(app.biaogew,'VariableNames',{'Y','x','y','u','v','Duv'},'RowNames',{'w90';'w80';'w70';'w60';'w50';'w40';'w30';'w20'});
            biaor = array2table(app.biaoger,'VariableNames',{'Y','x','y','u','v','Duv'},'RowNames',{'r90';'r80';'r70';'r60';'r50';'r40';'r30';'r20'} );
            biaog = array2table(app.biaoger,'VariableNames',{'Y','x','y','u','v','Duv'},'RowNames',{'g90';'g80';'g70';'g60';'g50';'g40';'g30';'g20'} );
            biaob = array2table(app.biaogeb,'VariableNames',{'Y','x','y','u','v','Duv'},'RowNames',{'b90';'b80';'b70';'b60';'b50';'b40';'b30';'b20'} );
            biaobl = array2table(app.biaogebl,'VariableNames',{'Y','x','y','u','v','Duv'},'RowNames',{'BL90';'BL80';'BL70';'BL60';'BL50';'BL40';'BL30';'BL20'} );
            
            
            app.biaoge=[biaow;biaor;biaog;biaob;biaobl];
            app.UITable.Data=app.biaoge;
            app.UITable.RowName=app.biaoge.Properties.RowNames;%复制到页面


            if app.jiaoduxuanxiang =="auto" %角度选项框如果是自动
                
                if app.jiaodu>20 %自动修改角度
                    
                    app.jiaodu = app.jiaodu-10;
                    
                else
                    app.jiaodu = 90;
                end
                
            else
                app.jiaodu = str2num(app.DropDown_jiaodu.Value);
            end
            xzhou=[90 80 70 60 50 40 30 20];
            %对比度下降画图
            duibidu = app.biaogebl(:,6);
            plot(app.duibidubiaoge, xzhou,duibidu);
            
            %Duv图
            cla(app.duvtu)
            hold(app.duvtu,'on')
            plot(app.duvtu,xzhou,app.biaogew(:,6),'k');
            plot(app.duvtu,xzhou,app.biaoger(:,6),'r');
            plot(app.duvtu,xzhou,app.biaoger(:,6),'g');
            plot(app.duvtu,xzhou,app.biaogeb(:,6),'b');
            
            
            %散点图
            cla(app.sezuobiao)
            hold(app.sezuobiao,'on')
            
            for i = 1:8
                scatter(app.sezuobiao,app.biaogew(i,2),app.biaogew(i,3),'k');
                scatter(app.sezuobiao,app.biaoger(i,2),app.biaoger(i,3),'r');
                scatter(app.sezuobiao,app.biaoger(i,2),app.biaoger(i,3),'g');
                scatter(app.sezuobiao,app.biaogeb(i,2),app.biaogeb(i,3),'blue');
            end


            app.Button.Enable = 'on'; %恢复按钮


        end

        % Button pushed function: Button_yuashi
        function Button_yuashiPushed(app, event)
            app.Button_yuashi.Enable = 'off' ;
            startMeasure_Callback(app,event)
            
            app.yuanshiguangpu=app.guangpu;
            app.yuanshiy=app.Y;
            app.Button_yuashi.Enable = 'on' ;
            
        end

        % Button pushed function: Button_beice
        function Button_beicePushed(app, event)
            app.Button_beice.Enable = 'off' ;
            startMeasure_Callback(app,event)
            
            app.beiceguangpu=app.guangpu;
            app.beicey=app.Y;
            app.toushe=app.beicey/app.yuanshiy;
            app.toushelv2=app.beiceguangpu./app.yuanshiguangpu;
            x=380:780;
            plot(app.toushelvtu,x,app.toushelv2);
            app.toushelv.Value=app.toushe;
            app.Button_beice.Enable = 'on' ;
            
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            CS2000_choseCom_App
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file,path] = uiputfile('*.csv');
            biaogename = fullfile(path,file);
            writetable(app.biaoge,biaogename,'WriteRowNames',true)  ;


        end

        % Button down function: toushelvtu
        function toushelvtuButtonDown(app, event)
            
        end

        % Value changed function: usercmfbox
        function usercmfboxValueChanged(app, event)
            %%自定义人眼特性曲线cmf   要求xls，3x380-780.第一列是光谱。
            
            value = app.usercmfbox.Value;
            if value == 1
                
                [file,path] = uigetfile({'*.xls';'*.xlsx';'*.csv'});
                xlsname = fullfile(path,file);
                duqucmf=readmatrix(xlsname);
                app.usercm=duqucmf(:,2:4);
                
            else
                clear app.usercm
            end
        end

        % Button pushed function: save_toushelv
        function save_toushelvPushed(app, event)
             [file,path] = uiputfile('*.xlsx');
            xlsname = fullfile(path,file);
            xlswrite(xlsname,app.toushelv2,'A1');
            xlswrite(xlsname,app.toushe,1,'C1');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CS2000_menue';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.file_menue = uimenu(app.figure1);
            app.file_menue.Text = '文件';
            app.file_menue.Tag = 'file_menue';
            app.Menu = uimenu(app.file_menue);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '连接设备';
            app.save_menue = uimenu(app.file_menue);
            app.save_menue.MenuSelectedFcn = createCallbackFcn(app, @save_menue_Callback, true);
            app.save_menue.Text = '保存测量数据';
            app.save_menue.Tag = 'save_menue';
            app.savePlot = uimenu(app.file_menue);
            app.savePlot.MenuSelectedFcn = createCallbackFcn(app, @savePlot_Callback, true);
            app.savePlot.Text = '保存光谱';
            app.savePlot.Tag = 'savePlot';
            app.storedMeasurements = uimenu(app.file_menue);
            app.storedMeasurements.MenuSelectedFcn = createCallbackFcn(app, @storedMeasurements_Callback, true);
            app.storedMeasurements.Separator = 'on';
            app.storedMeasurements.Text = '管理设备上的储存值';
            app.storedMeasurements.Tag = 'storedMeasurements';
            app.exit_menue = uimenu(app.file_menue);
            app.exit_menue.MenuSelectedFcn = createCallbackFcn(app, @exit_menue_Callback, true);
            app.exit_menue.Separator = 'on';
            app.exit_menue.Text = '退出';
            app.exit_menue.Tag = 'exit_menue';
            app.Untitled_1 = uimenu(app.figure1);
            app.Untitled_1.Text = '曲线';
            app.Untitled_1.Tag = 'Untitled_1';
            app.logarithmic_plot_menu = uimenu(app.Untitled_1);
            app.logarithmic_plot_menu.MenuSelectedFcn = createCallbackFcn(app, @changePlotScaleLog_Callback, true);
            app.logarithmic_plot_menu.Text = '线性显示';
            app.logarithmic_plot_menu.Tag = 'logarithmic_plot_menu';
            app.linear_plot_menu = uimenu(app.Untitled_1);
            app.linear_plot_menu.MenuSelectedFcn = createCallbackFcn(app, @changePlotScaleLinear_Callback, true);
            app.linear_plot_menu.Text = '指数显示';
            app.linear_plot_menu.Tag = 'linear_plot_menu';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Visible = 'off';
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.Status_Text = uilabel(app.figure1);
            app.Status_Text.Tag = 'Status_Text';
            app.Status_Text.Text = '状态：';
            app.instr_Status = uilabel(app.figure1);
            app.instr_Status.Tag = 'instr_Status';
            app.instr_Status.Text = '仪器：';
            app.openText = uilabel(app.figure1);
            app.openText.Tag = 'openText';
            app.openText.Text = '';
            app.measureText2 = uilabel(app.figure1);
            app.measureText2.Tag = 'measureText2';
            app.measureText2.Text = '预测量时间为：3秒';
            app.measureText = uilabel(app.figure1);
            app.measureText.Tag = 'measureText';
            app.measureText.Text = '显示1次测量的平均值。';
            app.TabGroup = uitabgroup(app.figure1);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '测量';
            app.axes1 = uiaxes(app.Tab);
            app.axes1.XLim = [380 780];
            app.axes1.Tag = 'axes1';
            app.uipanel1 = uipanel(app.Tab);
            app.uipanel1.Title = '比色数据：';
            app.uipanel1.Tag = 'uipanel1';
            app.colorDataText = uilabel(app.uipanel1);
            app.colorDataText.Tag = 'colorDataText';
            app.colorDataText.Text = '';
            app.colorDataNames = uilabel(app.uipanel1);
            app.colorDataNames.Tag = 'colorDataNames';
            app.colorDataNames.Text = '';
            app.numberOfMeas = uieditfield(app.Tab, 'text');
            app.numberOfMeas.Tag = 'numberOfMeas';
            app.numberOfMeas.Value = '1';
            app.staticNumberOfMeasText = uilabel(app.Tab);
            app.staticNumberOfMeasText.Tag = 'staticNumberOfMeasText';
            app.staticNumberOfMeasText.Text = '测量次数：';
            app.staticNDfilterText = uilabel(app.Tab);
            app.staticNDfilterText.Tag = 'staticNDfilterText';
            app.staticNDfilterText.Text = 'ND滤镜: ';
            app.NDfilterMenu = uidropdown(app.Tab);
            app.NDfilterMenu.Items = {'None', '10', '100'};
            app.NDfilterMenu.ValueChangedFcn = createCallbackFcn(app, @NDfilterMenu_Callback, true);
            app.NDfilterMenu.Tag = 'NDfilterMenu';
            app.NDfilterMenu.Value = 'None';
            app.lightSourceEdit = uieditfield(app.Tab, 'text');
            app.lightSourceEdit.Tag = 'lightSourceEdit';
            app.staticLightSourceText = uilabel(app.Tab);
            app.staticLightSourceText.Tag = 'staticLightSourceText';
            app.staticLightSourceText.Text = '光源：';
            app.commentsEdit = uitextarea(app.Tab);
            app.commentsEdit.Tag = 'commentsEdit';
            app.staticCommentsText = uilabel(app.Tab);
            app.staticCommentsText.Tag = 'staticCommentsText';
            app.staticCommentsText.Text = '评价：';
            app.normalLightBox = uicheckbox(app.Tab);
            app.normalLightBox.ValueChangedFcn = createCallbackFcn(app, @normalLightBox_Callback, true);
            app.normalLightBox.Tag = 'normalLightBox';
            app.normalLightBox.Text = '测量期间背光关闭';
            app.startMeasure = uibutton(app.Tab, 'push');
            app.startMeasure.ButtonPushedFcn = createCallbackFcn(app, @startMeasure_Callback, true);
            app.startMeasure.Tag = 'startMeasure';
            app.startMeasure.Text = '测量';
            app.biseshuju = uitable(app.Tab);
            app.biseshuju.ColumnName = {'X'; 'Y'; 'Z'; 'Y'; 'x'; 'y'; 'u'; 'v'; 'CCT'; 'JNCD'};
            app.biseshuju.RowName = {};
            app.cie19312Label = uilabel(app.Tab);
            app.cie19312Label.Text = 'cie1931-2';
            app.cie196410Label = uilabel(app.Tab);
            app.cie196410Label.Text = 'cie1964-10';
            app.cie1702Label = uilabel(app.Tab);
            app.cie1702Label.Text = 'cie170-2';
            app.cie17010Label = uilabel(app.Tab);
            app.cie17010Label.Text = 'cie170-10';
            app.cmfLabel = uilabel(app.Tab);
            app.cmfLabel.Text = '自定义cmf';
            app.usercmfbox = uicheckbox(app.Tab);
            app.usercmfbox.ValueChangedFcn = createCallbackFcn(app, @usercmfboxValueChanged, true);
            app.usercmfbox.Text = '';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '可视角度';
            app.duvtu = uiaxes(app.Tab_2);
            app.duvtu.Tag = 'TEST';
            app.duibidubiaoge = uiaxes(app.Tab_2);
            app.duibidubiaoge.Tag = 'duibidubiaoge';
            app.sezuobiao = uiaxes(app.Tab_2);
            app.sezuobiao.Tag = 'duibidubiaoge';
            app.Button = uibutton(app.Tab_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '测量';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'Y'; 'x'; 'y'; 'u'; 'v'; 'JNCD'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '当前角度';
            app.EditField_jiaodu = uieditfield(app.Tab_2, 'text');
            app.Label_3 = uilabel(app.Tab_2);
            app.Label_3.Text = '当前颜色';
            app.EditField_color = uieditfield(app.Tab_2, 'text');
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = '颜色';
            app.DropDown = uidropdown(app.Tab_2);
            app.DropDown.Items = {'自动', '白', '红', '绿', '蓝', '黑'};
            app.DropDown.ItemsData = {'auto', 'white', 'red', 'green', 'blue', 'black'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'white';
            app.DropDown_2Label = uilabel(app.Tab_2);
            app.DropDown_2Label.Text = '角度';
            app.DropDown_jiaodu = uidropdown(app.Tab_2);
            app.DropDown_jiaodu.Items = {'自动', '90', '80', '70', '60', '50', '40', '30', '20'};
            app.DropDown_jiaodu.ItemsData = {'auto', '90', '80', '70', '60', '50', '40', '30', '20'};
            app.DropDown_jiaodu.ValueChangedFcn = createCallbackFcn(app, @DropDown_jiaoduValueChanged, true);
            app.DropDown_jiaodu.Value = 'auto';
            app.Button_2 = uibutton(app.Tab_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '保存';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '透射率/反射率';
            app.toushelvtu = uiaxes(app.Tab_3);
            app.toushelvtu.XLim = [380 780];
            app.toushelvtu.YLim = [0 1.1];
            app.toushelvtu.YTick = [];
            app.toushelvtu.YScale = 'log';
            app.toushelvtu.YTickLabel = '';
            app.toushelvtu.ButtonDownFcn = createCallbackFcn(app, @toushelvtuButtonDown, true);
            app.toushelvtu.Tag = 'axes1';
            app.Button_yuashi = uibutton(app.Tab_3, 'push');
            app.Button_yuashi.ButtonPushedFcn = createCallbackFcn(app, @Button_yuashiPushed, true);
            app.Button_yuashi.Text = '原始光源';
            app.Button_beice = uibutton(app.Tab_3, 'push');
            app.Button_beice.ButtonPushedFcn = createCallbackFcn(app, @Button_beicePushed, true);
            app.Button_beice.Text = '被测物';
            app.EditFieldLabel = uilabel(app.Tab_3);
            app.EditFieldLabel.Text = '透射率';
            app.toushelv = uieditfield(app.Tab_3, 'numeric');
            app.save_toushelv = uibutton(app.Tab_3, 'push');
            app.save_toushelv.ButtonPushedFcn = createCallbackFcn(app, @save_toushelvPushed, true);
            app.save_toushelv.Text = '保存';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CS2000_menue_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CS2000_menue_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end